classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StimulationsystemPanel  matlab.ui.container.Panel
        DataPathPanel           matlab.ui.container.Panel
        PathSelectButton        matlab.ui.control.Button
        DataPathEditField       matlab.ui.control.EditField
        ControlPanel            matlab.ui.container.Panel
        RealtimemonitorButton   matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        AddSubjectButton        matlab.ui.control.Button
        SetParamsButton         matlab.ui.control.Button
        NextButton              matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        StatePanel              matlab.ui.container.Panel
        StateLamp               matlab.ui.control.Lamp
        StateLabel              matlab.ui.control.Label
        PhaseSelectPanel        matlab.ui.container.Panel
        RulesPathEditField      matlab.ui.control.EditField
        RulesSelectButton       matlab.ui.control.Button
        PhaseSelectTree         matlab.ui.container.CheckBoxTree
    end


    properties (Access = public)
        subjectInfo
        params
        dataPath
        pIDList
        protocolList
        protocol
        pIDIndex
        status
        mTimer
        activeID
        rulesPath
        nRepeat
        cueLag
        pIDsRules
        codes
        behavApp
        intervalMax
    end
    
    methods (Access = public)
        
        function timerInit(app)
            app.mTimer = timer;
            app.mTimer.Period = 1;
            app.mTimer.ExecutionMode = 'fixedSpacing';
            app.mTimer.TimerFcn = @(~, ~) timerHandler(app);
        end

        function timerHandler(app)
            currentTime = app.intervalMax * 60 - app.mTimer.TasksExecuted;
            
            if currentTime == 0
                NextButtonPushed(app, []);
            else
                app.NextButton.Text = ['Next phase(', num2str(currentTime), 's)'];
                drawnow;
            end

        end

    end
    
    methods (Access = private)
        
        function createNodes(app)
            app.PhaseSelectTree.Children.delete;

            tb = readtable(app.rulesPath);
            app.protocol = unique(tb.protocol, 'stable');
            app.protocolList.Text = cellfun(@(x, y) strcat(x, '-', y), tb.node0Hint, tb.nodeHint, "UniformOutput", false);
            app.protocolList.Text = unique(app.protocolList.Text, 'stable');
            app.protocolList.pID = unique(tb.pID, 'stable');

            % Create node0
            node0Hint = unique(tb.node0Hint, 'stable');
            for index = 1:length(node0Hint)
                node0(index) = uitreenode(app.PhaseSelectTree);
                node0(index).NodeData = 10000 + index;
                node0(index).Text = node0Hint{index};
            end

            % Create node
            n = 0;
            for index0 = 1:length(node0)
                idx = strcmp(tb.node0Hint, node0Hint{index0});
                pID = unique(tb.pID(idx), 'stable');
                nodeHint = unique(tb.nodeHint(idx), 'stable');
                for index = 1:length(pID)
                    n = n + 1;
                    node(n) = uitreenode(node0(index0));
                    node(n).NodeData = pID(index);
                    node(n).Text = nodeHint{index};
                end
            end

            expand(app.PhaseSelectTree);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Stimulation system';

            currentFolder = fileparts(mfilename("fullpath"));
            cd(currentFolder);
            addpath(genpath(currentFolder));

            app.intervalMax = 10; % min

            try
                load(fullfile(currentFolder, 'config', 'config.mat'), 'config');
                app.params = config.params;
                app.dataPath = config.dataPath;
                app.subjectInfo = config.subjectInfo;
                app.DataPathEditField.Value = config.dataPath;
                app.rulesPath = config.rulesPath;
            catch
                app.params.cueLag = 0;
                app.params.nRepeat = 40;
                app.params.fs = 384; % kHz
                app.params.volumn = 0.3;
                app.params.triggerType = 'None';
                app.params.address = hex2dec('378');
                app.params.COM = 1;
                app.params.useSettingnRepeat = false;
                app.params.Evts = fullfile(currentFolder, "config", "preset.mat");
                app.rulesPath = fullfile(currentFolder, 'rules\rules.xlsx');
            end

            app.status = 'stop';
            app.StateLamp.Color = [0.8, 0.8, 0.8];

            app.RulesPathEditField.Value = app.rulesPath;
            createNodes(app);

            movegui(app.UIFigure, "center");
        end

        % Button pushed function: AddSubjectButton
        function AddSubjectButtonPushed(app, event)
            subjectInfoInputApp(app, app.subjectInfo);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            try
                addpath(fileparts(mfilename("fullpath")), '-begin');
                InitializePsychSound;
                PsychPortAudio('Close');
                
                if isempty(app.dataPath)
                    app.dataPath = pwd;
                elseif ~contains(app.dataPath, '\') % relative path
                    app.dataPath = fullfile(pwd, app.dataPath);
                end
    
                config.params = app.params;
                config.dataPath = app.dataPath;
                config.rulesPath = app.rulesPath;
                config.subjectInfo = app.subjectInfo;
                config.time = datetime("now", 'Format', 'yyyy-mm-dd HH:MM:ss');
                save(fullfile(fileparts(mfilename("fullpath")), 'config', 'config.mat'), 'config');
    
                try
                    delete(app.params.ioObj);
                catch ME
                    throw(ME);
                end

                app.params.ioObj = InitTrigger(app.params.triggerType, app.params.COM);

                if isempty([app.PhaseSelectTree.CheckedNodes])
                    error("Please select phase");
                end

                app.pIDList = [app.PhaseSelectTree.CheckedNodes.NodeData];
                app.pIDList(app.pIDList >= 10000) = [];
                app.pIDIndex = 1;
                app.StateLabel.Text = app.protocolList.Text{app.protocolList.pID == app.pIDList(app.pIDIndex)};
                
                temp = fullfile(app.dataPath, [char(datetime("now", 'Format', 'yyyyMMdd')), '-', app.subjectInfo.ID]);
                mkdir(temp);
                save(fullfile(temp, 'config.mat'), 'config');
                app.DataPathEditField.Value = app.dataPath;
    
                app.AddSubjectButton.Enable = 'off';
                app.SetParamsButton.Enable = 'off';
                app.StartButton.Enable = 'off';
                app.StopButton.Enable = 'on';
                app.PhaseSelectPanel.Enable = 'off';
                app.DataPathPanel.Enable = 'off';
    
                app.status = 'start';
                app.StateLamp.Color = [0, 1, 0];
    
                drawnow;

                if ismember(app.pIDList(app.pIDIndex), app.activeID)
                    activeFcn(app);
                else
                    passiveFcn(app);
                end
                
            catch ME
                uialert(app.UIFigure, ME.message, "Error message");
            end
        end

        % Button pushed function: SetParamsButton
        function SetParamsButtonPushed(app, event)
            paramsSettingApp(app, app.params);
        end

        % Button pushed function: PathSelectButton
        function PathSelectButtonPushed(app, event)
            temp = uigetdir;

            if temp ~= 0
                app.dataPath = temp;
                app.DataPathEditField.Value = temp;
            end
        end

        % Value changed function: DataPathEditField
        function DataPathEditFieldValueChanged(app, event)
            app.dataPath = app.DataPathEditField.Value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.AddSubjectButton.Enable = 'on';
            app.SetParamsButton.Enable = 'on';
            app.StartButton.Enable = 'on';
            app.NextButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            app.PhaseSelectPanel.Enable = 'on';
            app.DataPathPanel.Enable = 'on';

            app.StateLabel.Text = ['Terminated at: ', app.protocolList.Text{app.protocolList.pID == app.pIDList(app.pIDIndex)}];

            try
                stop(app.mTimer);
                delete(app.mTimer);
            catch ME
                uialert(app.UIFigure, ME.message, "Error message");
            end

            app.NextButton.Text = 'Next phase';
            app.status = 'stop';
            app.StateLamp.Color = [0.8, 0.8, 0.8];
            drawnow;

            try
                playAudio(fullfile(fileparts(mfilename("fullpath")), "sounds\hint\terminate.mp3"), app.params.fs * 1000);
            catch ME
                uialert(app.UIFigure, ME.message, "Error message");
            end
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next phase';
            drawnow;
            
            try
                stop(app.mTimer);
                delete(app.mTimer);
            catch ME
                uialert(app.UIFigure, ME.message, "Error message");
            end

            app.pIDIndex = app.pIDIndex + 1;

            if app.pIDIndex <= length(app.pIDList)
                app.StateLabel.Text = app.protocolList.Text{app.protocolList.pID == app.pIDList(app.pIDIndex)};
                drawnow;
                
                if ismember(app.pIDList(app.pIDIndex), app.activeID)
                    activeFcn(app);
                else
                    passiveFcn(app);
                end

            else
                StopButtonPushed(app, event);
            end
            
        end

        % Value changed function: RulesPathEditField
        function RulesPathEditFieldValueChanged(app, event)
            app.rulesPath = app.RulesPathEditField.Value;

            try
                createNodes(app);
            catch ME
                uialert(app.UIFigure, ME.message, "Error message");
            end
        end

        % Button pushed function: RulesSelectButton
        function RulesSelectButtonPushed(app, event)
            [temp, path] = uigetfile(fullfile(fileparts(mfilename("fullpath")), 'rules\*.xlsx'), 'Pick a rules file');

            if temp ~= 0
                app.rulesPath = fullfile(path, temp);
                app.RulesPathEditField.Value = fullfile(path, temp);
                createNodes(app);
            end
        end

        % Button pushed function: RealtimemonitorButton
        function RealtimemonitorButtonPushed(app, event)
            app.behavApp = behaviorPlotApp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StimulationsystemPanel = uipanel(app.UIFigure);
            app.StimulationsystemPanel.Title = 'Stimulation system';
            app.PhaseSelectPanel = uipanel(app.StimulationsystemPanel);
            app.PhaseSelectPanel.Title = 'Rule settings';
            app.PhaseSelectTree = uitree(app.PhaseSelectPanel, 'checkbox');
            app.RulesSelectButton = uibutton(app.PhaseSelectPanel, 'push');
            app.RulesSelectButton.ButtonPushedFcn = createCallbackFcn(app, @RulesSelectButtonPushed, true);
            app.RulesSelectButton.Tag = 'pathSelectButton';
            app.RulesSelectButton.Text = '···';
            app.RulesPathEditField = uieditfield(app.PhaseSelectPanel, 'text');
            app.RulesPathEditField.ValueChangedFcn = createCallbackFcn(app, @RulesPathEditFieldValueChanged, true);
            app.RulesPathEditField.Tag = 'dataPath';
            app.RulesPathEditField.Placeholder = '<rule.xlsx> path';
            app.StatePanel = uipanel(app.StimulationsystemPanel);
            app.StatePanel.Title = 'Status';
            app.StateLabel = uilabel(app.StatePanel);
            app.StateLabel.Tag = 'stateLabel';
            app.StateLabel.Text = 'Halting';
            app.StateLamp = uilamp(app.StatePanel);
            app.ControlPanel = uipanel(app.StimulationsystemPanel);
            app.ControlPanel.Title = 'Control panel';
            app.StopButton = uibutton(app.ControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Tag = 'stopButton';
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Terminate';
            app.NextButton = uibutton(app.ControlPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tag = 'nextButton';
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next phase';
            app.SetParamsButton = uibutton(app.ControlPanel, 'push');
            app.SetParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SetParamsButtonPushed, true);
            app.SetParamsButton.Tag = 'setParamsButton';
            app.SetParamsButton.Text = 'Parameter settings';
            app.AddSubjectButton = uibutton(app.ControlPanel, 'push');
            app.AddSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddSubjectButtonPushed, true);
            app.AddSubjectButton.Tag = 'addSubjectButton';
            app.AddSubjectButton.Text = 'Subject infomation';
            app.StartButton = uibutton(app.ControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tag = 'startButton';
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.RealtimemonitorButton = uibutton(app.ControlPanel, 'push');
            app.RealtimemonitorButton.ButtonPushedFcn = createCallbackFcn(app, @RealtimemonitorButtonPushed, true);
            app.RealtimemonitorButton.Text = 'Real-time monitor';
            app.DataPathPanel = uipanel(app.StimulationsystemPanel);
            app.DataPathPanel.Title = 'Data storage path';
            app.DataPathEditField = uieditfield(app.DataPathPanel, 'text');
            app.DataPathEditField.ValueChangedFcn = createCallbackFcn(app, @DataPathEditFieldValueChanged, true);
            app.DataPathEditField.Tag = 'dataPath';
            app.DataPathEditField.Placeholder = 'Root path of data storage ([date-subject ID] directory will be created)';
            app.PathSelectButton = uibutton(app.DataPathPanel, 'push');
            app.PathSelectButton.ButtonPushedFcn = createCallbackFcn(app, @PathSelectButtonPushed, true);
            app.PathSelectButton.Tag = 'pathSelectButton';
            app.PathSelectButton.Text = '···';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end