classdef subjectInfoInputApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        CancelButton        matlab.ui.control.Button
        OKButton            matlab.ui.control.Button
        SubjectInfoPanel    matlab.ui.container.Panel
        NoteFieldsmarkedwitharerequiredLabel  matlab.ui.control.Label
        CommentEditField    matlab.ui.control.TextArea
        DiagnosisEditField  matlab.ui.control.TextArea
        ContactEditField    matlab.ui.control.EditField
        ProEditField        matlab.ui.control.EditField
        BirthdayDatePicker  matlab.ui.control.DatePicker
        GenderDropDown      matlab.ui.control.DropDown
        NameEditField       matlab.ui.control.EditField
        IDEditField         matlab.ui.control.EditField
        Label_8             matlab.ui.control.Label
        Label_7             matlab.ui.control.Label
        IDLabel             matlab.ui.control.Label
        Label_6             matlab.ui.control.Label
        Label_5             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label               matlab.ui.control.Label
    end

    
    properties (Access = private)
        app_parent
        subjectInfo
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_parent, subjectInfo)
            app.UIFigure.Name = 'Stimulation system';
            app.app_parent = app_parent;
            app.UIFigure.WindowState = "normal";

            if isempty(subjectInfo)
                subjectInfo = struct();
            end
            app.subjectInfo = subjectInfo;
            
            app.IDEditField.Value        = mu.ifelse(isfield("IDEditField"       , subjectInfo), @() subjectInfo.ID        , []);
            app.NameEditField.Value      = mu.ifelse(isfield("NameEditField"     , subjectInfo), @() subjectInfo.name      , []);
            app.GenderDropDown.Value     = mu.ifelse(isfield("GenderDropDown"    , subjectInfo), @() subjectInfo.gender    , '--Please select--');
            app.BirthdayDatePicker.Value = mu.ifelse(isfield("BirthdayDatePicker", subjectInfo), @() subjectInfo.birthDate , datetime("1990-01-01"));
            app.ProEditField.Value       = mu.ifelse(isfield("ProEditField"      , subjectInfo), @() subjectInfo.profession, '');
            app.CommentEditField.Value   = mu.ifelse(isfield("CommentEditField"  , subjectInfo), @() subjectInfo.contact   , '');
            app.DiagnosisEditField.Value = mu.ifelse(isfield("DiagnosisEditField", subjectInfo), @() subjectInfo.diagnosis , '');

            movegui(app.UIFigure, "center");
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.subjectInfo.ID = app.IDEditField.Value;
            app.subjectInfo.name = app.NameEditField.Value;
            app.subjectInfo.gender = app.GenderDropDown.Value;
            app.subjectInfo.birthDate = app.BirthdayDatePicker.Value;
            app.subjectInfo.profession = app.ProEditField.Value;
            app.subjectInfo.contact = app.CommentEditField.Value;
            app.subjectInfo.diagnosis = app.DiagnosisEditField.Value;
            app.subjectInfo.comment = app.CommentEditField.Value;

            % Validate
            if isempty(app.subjectInfo.ID) || isempty(app.subjectInfo.name) || strcmp(app.subjectInfo.gender, '--Please select--')
                uialert(app.UIFigure, "Fields marked with * are required.", "Validation error");
            else
                app.app_parent.subjectInfo = app.subjectInfo;

                if ~isempty(app.subjectInfo)
                    app.app_parent.StartButton.Enable = 'on';
                end

                delete(app);
            end

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubjectInfoPanel = uipanel(app.UIFigure);
            app.SubjectInfoPanel.Title = 'Basic subject infomation';
            app.Label = uilabel(app.SubjectInfoPanel);
            app.Label.Text = '*Gender';
            app.Label_2 = uilabel(app.SubjectInfoPanel);
            app.Label_2.Text = '*Birthday';
            app.Label_4 = uilabel(app.SubjectInfoPanel);
            app.Label_4.Text = '*Name';
            app.Label_5 = uilabel(app.SubjectInfoPanel);
            app.Label_5.Text = 'Profession';
            app.Label_6 = uilabel(app.SubjectInfoPanel);
            app.Label_6.Text = 'Contact';
            app.IDLabel = uilabel(app.SubjectInfoPanel);
            app.IDLabel.Text = '*ID';
            app.Label_7 = uilabel(app.SubjectInfoPanel);
            app.Label_7.Text = {'Diagnosis'; ''};
            app.Label_8 = uilabel(app.SubjectInfoPanel);
            app.Label_8.Text = 'Comment';
            app.IDEditField = uieditfield(app.SubjectInfoPanel, 'text');
            app.IDEditField.Tag = 'subjectID';
            app.NameEditField = uieditfield(app.SubjectInfoPanel, 'text');
            app.NameEditField.Tag = 'subjectName';
            app.GenderDropDown = uidropdown(app.SubjectInfoPanel);
            app.GenderDropDown.Items = {'--Please select--', 'Male', 'Female'};
            app.GenderDropDown.Tag = 'subjectGender';
            app.GenderDropDown.Value = '--Please select--';
            app.BirthdayDatePicker = uidatepicker(app.SubjectInfoPanel);
            app.BirthdayDatePicker.Editable = 'off';
            app.BirthdayDatePicker.Tag = 'subjectBirthDate';
            app.BirthdayDatePicker.Value = datetime([1990 1 1]);
            app.ProEditField = uieditfield(app.SubjectInfoPanel, 'text');
            app.ProEditField.Tag = 'subjectProfession';
            app.ContactEditField = uieditfield(app.SubjectInfoPanel, 'text');
            app.ContactEditField.Tag = 'subjectContact';
            app.ContactEditField.Placeholder = 'Phone number/email';
            app.DiagnosisEditField = uitextarea(app.SubjectInfoPanel);
            app.DiagnosisEditField.Tag = 'subjectDiagnosis';
            app.DiagnosisEditField.Placeholder = 'Separate with comma';
            app.CommentEditField = uitextarea(app.SubjectInfoPanel);
            app.CommentEditField.Tag = 'comment';
            app.NoteFieldsmarkedwitharerequiredLabel = uilabel(app.SubjectInfoPanel);
            app.NoteFieldsmarkedwitharerequiredLabel.Text = 'Note: Fields marked with * are required';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tag = 'confirmButton';
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = subjectInfoInputApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end